classdef Numerical_Methods < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RootFinderTab                  matlab.ui.container.Tab
        RootResultsTextArea            matlab.ui.control.TextArea
        RootResultsTextAreaLabel       matlab.ui.control.Label
        StepSizeEditFieldLabel         matlab.ui.control.Label
        StepSizeEditField              matlab.ui.control.NumericEditField
        EquationfXEditField            matlab.ui.control.EditField
        EquationfXEditFieldLabel       matlab.ui.control.Label
        MaxIterationsEditField         matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel    matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel        matlab.ui.control.Label
        UpperBoundEditField            matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel       matlab.ui.control.Label
        LowerBoundEditField            matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel       matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        CalculateRootButton            matlab.ui.control.Button
        SelectMethodDropDown           matlab.ui.control.DropDown
        SelectMethodDropDownLabel      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        MatrixOperationsTab            matlab.ui.container.Tab
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        MatrixBTextArea                matlab.ui.control.TextArea
        MatrixBTextAreaLabel           matlab.ui.control.Label
        MatrixATextArea                matlab.ui.control.TextArea
        MatrixATextAreaLabel           matlab.ui.control.Label
        ClearButton_2                  matlab.ui.control.Button
        CalculateMatrixButton          matlab.ui.control.Button
        SelectOperationsDropDown       matlab.ui.control.DropDown
        SelectOperationsDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateRootButton
        function CalculateRootButtonPushed(app, event)
             % 1. VALIDATION: Check each field individually
            if isempty(app.EquationfXEditField.Value)
                uialert(app.UIFigure, 'Please enter an equation (e.g., x^2 - 5).', 'Missing Input');
                return;
            elseif isempty(app.LowerBoundEditField.Value)
                uialert(app.UIFigure, 'Please enter a value for the Start Point / Lower Bound.', 'Missing Input');
                return;
            elseif strcmp(app.UpperBoundEditField.Visible,  'on') && isempty(app.UpperBoundEditField.Value)
                uialert(app.UIFigure, 'Please enter a value for the Upper Bound.', 'Missing Input');
                return;
            elseif strcmp(app.StepSizeEditField.Visible, 'on') && isempty(app.StepSizeEditField.Value)
                uialert(app.UIFigure, 'Please enter a Step Size.', 'Missing Input');
                return;
            elseif isempty(app.ToleranceEditField.Value) || isempty(app.MaxIterationsEditField.Value)
                uialert(app.UIFigure, 'Please ensure Tolerance and Max Iterations are filled.', 'Missing Input');
                return;
            end
            
            % 2. SANITIZE AND CHECK FOR VALID EQUATION
            raw_eq = app.EquationfXEditField.Value;
            % Fix implicit multiplication (e.g., '2x' -> '2*x')
            eq_str = regexprep(raw_eq, '(\d+)([a-zA-Z])', '$1*$2');
            
            try
                syms x;
                test_eq = str2sym(eq_str);
                if ~isempty(setdiff(symvar(test_eq), x))
                    uialert(app.UIFigure, 'Equation must only contain variable "x".', 'Input Error');
                    return;
                end
            catch
                uialert(app.UIFigure, 'Invalid equation format. Please check your syntax.', 'Input Error');
                return;
            end
            
            % 3. COLLECT INPUTS
            method = app.SelectMethodDropDown.Value; 
            tol = app.ToleranceEditField.Value;
            maxIter = app.MaxIterationsEditField.Value;
            
            if strcmp(method, 'Increment')
                a = app.LowerBoundEditField.Value;     
                b = app.StepSizeEditField.Value;       
            elseif strcmp(method, 'Newton-Raphson')
                a = app.LowerBoundEditField.Value;     
                b = 0;                                 
            else
                a = app.LowerBoundEditField.Value;     
                b = app.UpperBoundEditField.Value;     
            end
            
            % 4. EXECUTE ALGORITHM
            [root, T] = root_algorithms(method, eq_str, a, b, tol, maxIter);
            
            % 5. UPDATE TABLE
            app.UITable.Data = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.RootResultsTextArea.Value = {sprintf('Root ≈ %.8f', root)};
            
            % 6. PLOTTING
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            % Use the sanitized eq_str here to prevent plot crashes
            f_func = matlabFunction(str2sym(eq_str));
            
            count = height(T);
            if strcmp(method, 'Secant') || strcmp(method, 'Newton-Raphson')
                x_vals = T.Xi_plus_1(1:count);
            else
                x_vals = T.XR(1:count);
            end
            
            x_start = min([a, b, min(x_vals)]) - 1;
            x_end   = max([a, b, max(x_vals)]) + 1;
            x_range = linspace(x_start, x_end, 200);
            
            plot(app.UIAxes, x_range, f_func(x_range), 'Color', [0.6 0.6 0.6], 'LineWidth', 1.5);
            plot(app.UIAxes, x_vals, f_func(x_vals), 'ro-', 'LineWidth', 1.5, 'MarkerFaceColor', 'r');
            
            xlim(app.UIAxes, [x_start, x_end]);
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, ['Convergence Path: ', method]);
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            box(app.UIAxes, 'on');

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
                % Reset the UI components
                app.EquationfXEditField.Value = '';
                app.LowerBoundEditField.Value = 0;
                app.UpperBoundEditField.Value = 0;
                app.ToleranceEditField.Value = 1e-4;
                app.MaxIterationsEditField.Value = 50;

                % Clear the table and the plot
                app.UITable.Data = [];
                cla(app.UIAxes); % This clears the plot on your UIAxes
                app.RootResultsTextArea.Value = ''; % Clear result text
        end

        % Button pushed function: CalculateMatrixButton
        function CalculateMatrixButtonPushed(app, event)
       
            try
                % 1. Extract the raw inputs from the text areas
                rawA = app.MatrixATextArea.Value;
                rawB = app.MatrixBTextArea.Value;
                operation = app.SelectOperationsDropDown.Value; 
                
                % 2. Helper Logic: Check if a text area is completely blank
                isAEmpty = isempty(rawA) || (iscell(rawA) && (isempty(rawA) || all(cellfun(@(x) isempty(strtrim(x)), rawA))));
                isBEmpty = isempty(rawB) || (iscell(rawB) && (isempty(rawB) || all(cellfun(@(x) isempty(strtrim(x)), rawB))));
                
                % 3. GLOBAL BLANK CHECK
                if isAEmpty && isBEmpty
                    error('All input fields are empty! Please enter your matrix data before clicking Calculate.');
                end
                
                if isAEmpty
                    error('Matrix A text box is empty! Please enter data for Matrix A.');
                end
                
                % 4. Check if Matrix B text box is active/required
                requiresMatrixB = strcmp(app.MatrixBTextArea.Enable, 'on');
                if requiresMatrixB && isBEmpty
                    error('The second input field is empty! This operation requires both inputs.');
                end
                
                % 5. Numeric Parsing: Convert strings to numbers using your trusted char method
                matrixA = str2num(char(rawA)); %#ok<ST2NM>
                if isempty(matrixA)
                    error('Matrix A formatting is invalid. Separate numbers with spaces and rows with new lines.');
                end
                
                matrixB = [];
                if requiresMatrixB
                    matrixB = str2num(char(rawB)); %#ok<ST2NM>
                    if isempty(matrixB)
                        error('The second input is invalid. Please enter numbers matching the required format.');
                    end
                end
                
                % 6. Route everything directly to your backend Toolkit
                result = matrix_toolkit(operation, matrixA, matrixB);
                
                % 7. Output Result back to UI with native multi-line formatting
                if ischar(result)
                    app.ResultsTextArea.Value = result;
                else
                    % Converts the numerical matrix rows directly into a vertical layout
                    app.ResultsTextArea.Value = cellstr(num2str(result));
                end
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Matrix Tool Error');
            
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
              % Clear Matrix A and B using formal blank string objects
            app.MatrixATextArea.Value = "";
            app.MatrixBTextArea.Value = "";
            
            % Clear the Results display text area
            app.ResultsTextArea.Value = "";
            
            % Force MATLAB to instantly update the graphics window
            drawnow;
        
        end

        % Value changed function: SelectMethodDropDown
        function SelectMethodDropDownValueChanged(app, event)
    
    method = app.SelectMethodDropDown.Value;
    
    % 1. Default State: Show bounds, hide step size
    app.LowerBoundEditField.Visible = 'on';
    app.UpperBoundEditField.Visible = 'on';
    app.UpperBoundEditFieldLabel.Visible = 'on';
    app.StepSizeEditField.Visible = 'off';
    
    
    % Reset default labels
    app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
    app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
    
    % 2. Apply Custom Logic based on method selection
    switch method
        case 'Newton-Raphson'
            app.LowerBoundEditFieldLabel.Text = 'Initial Guess';
            app.UpperBoundEditField.Visible = 'off'; 
            app.UpperBoundEditFieldLabel.Visible = 'off';
            
        case 'Secant'
            app.LowerBoundEditFieldLabel.Text = 'First Guess';
            app.UpperBoundEditFieldLabel.Text = 'Second Guess';
            
        case 'Increment'
            % Start Point and Step Size logic
            app.LowerBoundEditFieldLabel.Text = 'Start Point';
            app.StepSizeEditField.Visible = 'on'; 
           
            app.UpperBoundEditField.Visible = 'off';
            app.UpperBoundEditFieldLabel.Visible = 'off';
            
        case 'Bisection'
            % Uses default Lower/Upper bounds
            
        case 'Regula-Falsi'
            % Uses default Lower/Upper bounds
            % Add any specific labeling if needed
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            
    end

        end

        % Value changed function: SelectOperationsDropDown
        function SelectOperationsDropDownValueChanged(app, event)
            % 1. Get the selected operation
            op = app.SelectOperationsDropDown.Value;

            % 2. Default state: Reset labels and enable B
            app.MatrixBTextArea.Enable = 'on';
            app.MatrixBTextAreaLabel.Text = 'Matrix B';
            app.MatrixBTextArea.Value = ''; % Clear previous input

            % 3. Apply logic based on operation type
            switch op
                case {'Adjoint', 'Inverse Matrix', 'Determinants', 'Transpose of Matrix'}
                    % These operations only require Matrix A
                    app.MatrixBTextArea.Enable = 'off';
                    app.MatrixBTextAreaLabel.Text = 'Matrix B (Not Required)';

                case 'Power of Matrix'
                    % Requires Matrix A and a scalar power
                    app.MatrixBTextAreaLabel.Text = 'Power (Scalar)';

                case 'Equations'
                    % Requires Matrix A and a constant vector B
                    app.MatrixBTextAreaLabel.Text = 'Constant Vector (B)';

                case {'Matrix Addition', 'Matrix Multiplication'}
                    % These keep the standard Matrix B label
                    app.MatrixBTextAreaLabel.Text = 'Matrix B';
            end
            
        end

        % Callback function
        function RootResultsTextAreaValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RootFinderTab = uitab(app.TabGroup);
            app.RootFinderTab.Title = 'Root Finder';
            app.UIAxes = uiaxes(app.RootFinderTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RootFinderTab);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.SelectMethodDropDownLabel = uilabel(app.RootFinderTab);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.RootFinderTab);
            app.SelectMethodDropDown.Items = {'Increment', 'Bisection', 'Regula-Falsi', 'Newton-Raphson', 'Secant'};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMethodDropDownValueChanged, true);
            app.SelectMethodDropDown.Value = 'Increment';
            app.CalculateRootButton = uibutton(app.RootFinderTab, 'push');
            app.CalculateRootButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRootButtonPushed, true);
            app.CalculateRootButton.Text = 'Calculate Root';
            app.ClearButton = uibutton(app.RootFinderTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LowerBoundEditFieldLabel = uilabel(app.RootFinderTab);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.RootFinderTab, 'numeric');
            app.UpperBoundEditFieldLabel = uilabel(app.RootFinderTab);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.RootFinderTab, 'numeric');
            app.ToleranceEditFieldLabel = uilabel(app.RootFinderTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.RootFinderTab, 'numeric');
            app.MaxIterationsEditFieldLabel = uilabel(app.RootFinderTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.RootFinderTab, 'numeric');
            app.EquationfXEditFieldLabel = uilabel(app.RootFinderTab);
            app.EquationfXEditFieldLabel.Text = 'Equation f(X)';
            app.EquationfXEditField = uieditfield(app.RootFinderTab, 'text');
            app.StepSizeEditField = uieditfield(app.RootFinderTab, 'numeric');
            app.StepSizeEditFieldLabel = uilabel(app.RootFinderTab);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.RootResultsTextAreaLabel = uilabel(app.RootFinderTab);
            app.RootResultsTextAreaLabel.Text = 'Root Results';
            app.RootResultsTextArea = uitextarea(app.RootFinderTab);
            app.MatrixOperationsTab = uitab(app.TabGroup);
            app.MatrixOperationsTab.Title = 'Matrix Operations';
            app.SelectOperationsDropDownLabel = uilabel(app.MatrixOperationsTab);
            app.SelectOperationsDropDownLabel.Text = 'Select Operations';
            app.SelectOperationsDropDown = uidropdown(app.MatrixOperationsTab);
            app.SelectOperationsDropDown.Items = {'Matrix Addition', 'Matrix Multiplication', 'Adjoint', 'Inverse Matrix', 'Determinants', 'Power of Matrix', 'Equations', 'Transpose of Matrix'};
            app.SelectOperationsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectOperationsDropDownValueChanged, true);
            app.SelectOperationsDropDown.Value = 'Matrix Addition';
            app.CalculateMatrixButton = uibutton(app.MatrixOperationsTab, 'push');
            app.CalculateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMatrixButtonPushed, true);
            app.CalculateMatrixButton.Text = 'Calculate Matrix';
            app.ClearButton_2 = uibutton(app.MatrixOperationsTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.MatrixATextAreaLabel = uilabel(app.MatrixOperationsTab);
            app.MatrixATextAreaLabel.Text = 'Matrix A';
            app.MatrixATextArea = uitextarea(app.MatrixOperationsTab);
            app.MatrixBTextAreaLabel = uilabel(app.MatrixOperationsTab);
            app.MatrixBTextAreaLabel.Text = 'Matrix B';
            app.MatrixBTextArea = uitextarea(app.MatrixOperationsTab);
            app.ResultsTextAreaLabel = uilabel(app.MatrixOperationsTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.MatrixOperationsTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Numerical_Methods

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end